classdef Calc_LT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Cálculo ...
        TabGroup              matlab.ui.container.TabGroup       % Ingresar...
        CalculoParametros     matlab.ui.container.Tab            % Ingresar...
        Label                 matlab.ui.control.Label            % Cálculo ...
        DatosEntrada          matlab.ui.container.Panel          % Datos de...
        Panel                 matlab.ui.container.Panel          % General
        LabelDropDown         matlab.ui.control.Label            % Tipo de ...
        TipoConductor         matlab.ui.control.DropDown         % Partridg...
        LabelNumericEditField matlab.ui.control.Label            % Longitud...
        LongitudLinea         matlab.ui.control.NumericEditField % [0 1000]
        Label3                matlab.ui.control.Label            % Tensión ...
        TensionOperacion      matlab.ui.control.NumericEditField % [0 10000...
        Label6                matlab.ui.control.Label            % Potencia...
        PotenciaCarga         matlab.ui.control.NumericEditField % [0 10000...
        Label7                matlab.ui.control.Label            % Factor d...
        FactorPotencia        matlab.ui.control.NumericEditField % [0 1]
        Label8                matlab.ui.control.Label            % Temp. de...
        TempOp                matlab.ui.control.NumericEditField % [0 150]
        DistribucionPanel     matlab.ui.container.Panel          % Distribu...
        Label2                matlab.ui.control.Label            % Distribu...
        Distribucion          matlab.ui.control.DropDown         % Cuadrada...
        Label9                matlab.ui.control.Label            % Distanci...
        Distancia             matlab.ui.control.NumericEditField % [0 50]
        RLC                   matlab.ui.container.Panel          % Parámetr...
        LabelTextArea         matlab.ui.control.Label            % Resisten...
        Res                   matlab.ui.control.TextArea        
        Label4                matlab.ui.control.Label            % Inductan...
        Ind                   matlab.ui.control.TextArea        
        Label5                matlab.ui.control.Label            % Capacita...
        Cap                   matlab.ui.control.TextArea        
        BotonCalcRLC          matlab.ui.control.Button           % Calcular
        TablaComparativa      matlab.ui.container.Tab            % Tabla Co...
        CuadroComparativo     matlab.ui.container.Panel          % Cuadro c...
        LabelTextArea2        matlab.ui.control.Label            % Línea
        Info                  matlab.ui.container.Panel         
        Label10               matlab.ui.control.Label            % V Trans [V]
        MagVTrans1            matlab.ui.control.TextArea        
        Label12               matlab.ui.control.Label            % I Trans [A]
        MagITrans1            matlab.ui.control.TextArea        
        Label13               matlab.ui.control.Label            % Fase I T...
        FasITrans1            matlab.ui.control.TextArea        
        Label14               matlab.ui.control.Label            % V Línea [V]
        MagVLinea1            matlab.ui.control.TextArea        
        Label11               matlab.ui.control.Label            % Fase V T...
        FasVTrans1            matlab.ui.control.TextArea        
        Label15               matlab.ui.control.Label            % Fase V L...
        FasVLinea1            matlab.ui.control.TextArea        
        Label16               matlab.ui.control.Label            % %Reg
        Reg1                  matlab.ui.control.TextArea        
        MagVTrans2            matlab.ui.control.TextArea        
        FasVTrans2            matlab.ui.control.TextArea        
        MagITrans2            matlab.ui.control.TextArea        
        FasITrans2            matlab.ui.control.TextArea        
        MagVLinea2            matlab.ui.control.TextArea        
        FasVLinea2            matlab.ui.control.TextArea        
        Reg2                  matlab.ui.control.TextArea        
        MagVTrans3            matlab.ui.control.TextArea        
        FasVTrans3            matlab.ui.control.TextArea        
        MagITrans3            matlab.ui.control.TextArea        
        FasITrans3            matlab.ui.control.TextArea        
        MagVLinea3            matlab.ui.control.TextArea        
        FasVLinea3            matlab.ui.control.TextArea        
        Reg3                  matlab.ui.control.TextArea        
        L_corta               matlab.ui.control.TextArea         % Línea Corta
        L_media               matlab.ui.control.TextArea         % Línea Media
        L_larga               matlab.ui.control.TextArea         % Línea Larga
        Label17               matlab.ui.control.Label            % Comparac...
    end


    properties (Access = private)
        SeccTrans_glob
        RMG_glob % Description
        DMG_glob % Description
        Distrib_glob % Description
        
        Long_in
        Vop_in
        Pcarga_in
        FP_in
        Temp_in
        Dist_in
        
        Res_calc
        Ind_calc
        Cap_calc
        XL
        BC
        Z_linea
        Y_linea
        V_op % Description
        I_op % Description
    end

    methods (Access = private)
        
        function R_ca = getRca(app, long, secc_trans, num_hilos)
            Resistividad = 17; %(Ohm*cmil)/ft
            long = long*3.28084;
            r1 = Resistividad*long/secc_trans;
            if num_hilos > 3
               r1 = r1 + r1*0.05;
            else
               r1 = r1 + r1*0.04;
            end
            R_ca = r1;
        end
        
        function R_L = tempFunctionRes(app, R1, t1, t2)
            T = 228; %°C, para conductores de aluminio estirado
            R_L = R1*(T+t2)/(T+t1);
        end
        
        function DMG = DMG_square(app, D)
            a = D*D*sqrt(2*D^2);
            DMG = nthroot(a,3);
        end
        
        function DMG = DMG_equilat(app, D)
            DMG = D;
        end
        
        function DMG = DMG_straight(app, D)
            a = 2*D*D*D;
            DMG = nthroot(a,3);
        end
        
        function DMG = DMGbyDistribution(app, D, dis)
            a = 0;
            switch dis
                case 1
                    a = DMG_square(app, D);
                case 2
                    a = DMG_equilat(app, D);
                case 3
                    a = DMG_straight(app, D);
                case 4
                    a = DMG_straight(app, D);
                otherwise
                    a = 0;
            end
            DMG = a;
        end

        function XL_L = lineInductiveReactance(app, f, d, DMG_L, RMG_L)
            DMG_L = DMG_L*3.28084;
            l = (2*10^(-7))*log(DMG_L/RMG_L);   %inductividad por unidad de longitud en H/m
            L = l*d;                            %inductividad de la línea total en H
            XL_L = [2*pi*f*L, L];               %Reactancia de la línea en Ohms
        end
        
        function BC_L = lineCapacitiveSusceptance(app, f, d, DMG_C, RMG_C)
            DMG_C = DMG_C*3.28084;
            c = (2*pi*(8.85*10^(-12)))/(log(DMG_C/RMG_C));  %capacitancia por unidad de longitud en F/m
            C = c*d;                                   %capacitancia de la línea total en F
            BC_L = [2*pi*f*C, C];                           %Susceptancia de la línea en Ohms
        end

        function I_r = operationCurrent(app, V, P, fp)
            I = P/(abs(fp)*(V/sqrt(3)));
            ang = acosd(abs(fp));
            sign = fp/abs(fp);
            re = I*cos(ang);
            im = I*sin(ang);
            I_r = re + im*fp/abs(fp)*1i;
        end
        
        function T = lineMatrix(app, A, B, C, D, V_r, I_r)
            M = [A B; C D];
            R = [V_r; I_r];
            
            T = M*R;
        end
        
        function LS = line_Short(app, V_op, I_op, Z)
            LS = lineMatrix(app, 1, Z, 0, 1, V_op, I_op);
        end
        
        function LM = line_Medium(app, V_op, I_op, Z, Y)
            a = 1 + (Z*Y/2);
            c = Y*(1 + (Z*Y/4));
            LM = lineMatrix(app, a, Z, c, a, V_op, I_op);
        end
        
        
        function LL = line_Long(app, V_op, I_op, Z, Y)
            gl = sqrt(Z*Y);
            
            Z1 = Z*(sinh(gl)/gl);
            Y1 = Y*(tanh(gl/2)/(gl/2));
            
            a = 1 + (Z1*Y1/2);
            c = Y1*(1 + (Z1*Y1/4));
            
            LL = lineMatrix(app, a, Z1, c, a, V_op, I_op);
        end
        
        function R = results(app, T, V)
            %Magnitud de VT
            V_T_mag_s = abs(T(1,1));
            V_T_mag_m = abs(T(1,2));
            V_T_mag_l = abs(T(1,3));
            V_T_mag = [V_T_mag_s; V_T_mag_m; V_T_mag_l];
            
            %Fase de VT
            V_T_ph_s = atand(iamg(T(1,1))/real(T(1,1)));
            V_T_ph_m = atand(iamg(T(1,2))/real(T(1,2)));
            V_T_ph_l = atand(iamg(T(1,3))/real(T(1,3)));
            V_T_ph = [V_T_ph_s; V_T_ph_m; V_T_ph_l];
            
            %Magnitud de IT
            I_T_mag_s = abs(T(2,1));
            I_T_mag_m = abs(T(2,2));
            I_T_mag_l = abs(T(2,3));
            I_T_mag = [I_T_mag_s; I_T_mag_m; I_T_mag_l];
            
            %Fase de IT
            I_T_ph_s = atand(iamg(T(2,1))/real(T(2,1)));
            I_T_ph_m = atand(iamg(T(2,2))/real(T(2,2)));
            I_T_ph_l = atand(iamg(T(2,3))/real(T(2,3)));
            I_T_ph = [I_T_ph_s; I_T_ph_m; I_T_ph_l];
            
            %Caída de tensión en la línea
            V_op = V/sqrt(3);
            Vl_s = T(1,1)-V_op;
            Vl_m = T(1,2)-V_op;
            Vl_l = T(1,3)-V_op;
            
            %Magnitud
            V_L_mag_s = abs(Vl_s);
            V_L_mag_m = abs(Vl_m);
            V_L_mag_l = abs(Vl_l);
            V_L_mag = [V_L_mag_s; V_L_mag_m; V_L_mag_l];
            
            %Fase
            V_L_ph_s = atand(iamg(Vl_s)/real(Vl_s));
            V_L_ph_m = atand(iamg(Vl_m)/real(Vl_m));
            V_L_ph_l = atand(iamg(Vl_l)/real(Vl_l));
            V_L_ph = [V_L_ph_s; V_L_ph_m; V_L_ph_l];
            
            %Porcentaje de regulación
            Reg_s = ((T(1,1)-V_op)/T(1,1))*100;
            %Faltan las otras regulaciones
            %Y la eficiencia
            
            Reg = [Reg_s; 0; 0];
            
            R = [V_T_mag, V_T_ph, I_T_mag, I_T_ph, V_L_mag, V_L_ph, Reg]
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RMG_glob = 0;
            app.DMG_glob = 0;
            app.Distrib_glob = 0;
            app.SeccTrans_glob = 0;
            
            app.Long_in = 0;
            app.Vop_in = 0;
            app.Pcarga_in = 0;
            app.FP_in = 0;
            app.Temp_in = 0;
            app.Dist_in = 0;
            
            app.Res_calc = 0;
            app.Ind_calc = 0;
            app.Cap_calc = 0;
            app.XL = 0;
            app.BC = 0;
            app.Z_linea = 0;
            app.Y_linea = 0;
            app.V_op = 0; % Description
            app.I_op = 0;% Description
        end

        % TipoConductor value changed function
        function TipoConductorValueChanged(app, event)
            Conductores = {'Partridge', 'Dove', 'Drake', 'Bluejay', 'Pheasant', 'Bluebird'};
            RMG_cond = [0.0217, 0.0314, 0.0373, 0.0415, 0.0466, 0.0586];
            SeccTrans_cond = [266800, 556500, 795000, 1113000, 1272000, 2156000]; %[cmil]
            app.RMG_glob = 0;
            value = app.TipoConductor.Value;
            for i=1:6
               if strcmp(value, Conductores(i))
                   app.RMG_glob = RMG_cond(i);
                   app.SeccTrans_glob = SeccTrans_cond(i);
               end
            end
            %app.Ind.Value = num2str(app.SeccTrans_glob);
        end

        % Distribucion value changed function
        function DistribucionValueChanged(app, event)
            Distribuciones = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distrib_glob = 0;
            value = app.Distribucion.Value;
            for i=1:4
               if strcmp(value, Distribuciones(i))
                   app.Distrib_glob = i;
               end
            end
            %DMGbyDistribution(app, app.D_glob)
            %app.Ind.Value = num2str(app.Distrib);
        end

        % LongitudLinea value changed function
        function LongitudLineaValueChanged(app)
            value = app.LongitudLinea.Value;
            app.Long_in = value*1000;
        end

        % TensionOperacion value changed function
        function TensionOperacionValueChanged(app)
            value = app.TensionOperacion.Value;
            app.Vop_in = value;
        end

        % PotenciaCarga value changed function
        function PotenciaCargaValueChanged(app)
            value = app.PotenciaCarga.Value;
            app.Pcarga_in = value;
        end

        % FactorPotencia value changed function
        function FactorPotenciaValueChanged(app)
            value = app.FactorPotencia.Value;
            app.FP_in = value;
        end

        % TempOp value changed function
        function TempOpValueChanged(app)
            value = app.TempOp.Value;
            app.Temp_in = value;
        end

        % Distancia value changed function
        function DistanciaValueChanged(app)
            value = app.Distancia.Value;
            app.Dist_in = value;
        end

        % BotonCalcRLC button pushed function
        function BotonCalcRLCButtonPushed(app)
            app.Res_calc = tempFunctionRes(app, getRca(app, app.Long_in, app.SeccTrans_glob, 100), 20, app.Temp_in);
            app.Res.Value = num2str(app.Res_calc);
            
            app.DMG_glob = DMGbyDistribution(app, app.Dist_in, app.Distrib_glob);

            L = lineInductiveReactance(app, 60, app.Long_in, app.DMG_glob, app.RMG_glob);
            app.XL = L(1);
            app.Ind_calc = L(2);
            %app.Ind.Value = num2str(app.Ind_calc);
            app.Ind.Value = num2str(app.XL);
            
            C = lineCapacitiveSusceptance(app, 60, app.Long_in, app.DMG_glob, app.RMG_glob);
            app.BC = C(1);
            app.Cap_calc = C(2);
            %app.Cap.Value = num2str(app.Cap_calc);
            app.Cap.Value = num2str(app.BC);
            
            app.Z_linea = app.Res_calc + (1i*app.XL);
            app.Y_linea = 1i*app.BC;
            
            app.V_op = app.Vop_in/sqrt(3);
            app.I_op = operationCurrent(app, app.Vop_in, app.Pcarga_in, app.FP_in);
            
            s = line_Short(app, app.V_op, app.I_op, app.Z_linea);
            m = line_Medium(app, app.V_op, app.I_op, app.Z_linea, app.Y_linea);
            l = line_Long(app, app.V_op, app.I_op, app.Z_linea, app.Y_linea);
            
            T = [s, m, l];
            R = results(app, T, app.V_op*sqrt(3))
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Cálculo de Parámetros';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.CalculoParametros = uitab(app.TabGroup);
            app.CalculoParametros.Units = 'pixels';
            app.CalculoParametros.Title = 'Ingresar Datos';
            app.Label = uilabel(app.CalculoParametros);
            app.Label.Text = 'Cálculo de parámetros de Líneas de Transmisión';
            app.DatosEntrada = uipanel(app.CalculoParametros);
            app.DatosEntrada.Title = 'Datos de entrada';
            app.DatosEntrada.Units = 'pixels';
            app.Panel = uipanel(app.DatosEntrada);
            app.Panel.Title = 'General';
            app.Panel.Units = 'pixels';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Tipo de conductor';
            app.TipoConductor = uidropdown(app.Panel);
            app.TipoConductor.Items = {'Partridge', 'Drake', 'Bluejay', 'Bluebird', 'Dove', 'Pheasant'};
            app.TipoConductor.ValueChangedFcn = createCallbackFcn(app, @TipoConductorValueChanged, true);
            app.TipoConductor.Value = 'Partridge';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Longitud de la línea [km]';
            app.LongitudLinea = uieditfield(app.Panel, 'numeric');
            app.LongitudLinea.ValueChangedFcn = createCallbackFcn(app, @LongitudLineaValueChanged);
            app.LongitudLinea.Limits = [0 1000];
            app.LongitudLinea.ValueDisplayFormat = '%5.2f';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Tensión de operación [kV]';
            app.TensionOperacion = uieditfield(app.Panel, 'numeric');
            app.TensionOperacion.ValueChangedFcn = createCallbackFcn(app, @TensionOperacionValueChanged);
            app.TensionOperacion.Limits = [0 1000000000];
            app.TensionOperacion.ValueDisplayFormat = '%5.2f';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'Potencia de la carga [W]';
            app.PotenciaCarga = uieditfield(app.Panel, 'numeric');
            app.PotenciaCarga.ValueChangedFcn = createCallbackFcn(app, @PotenciaCargaValueChanged);
            app.PotenciaCarga.Limits = [0 1000000000];
            app.PotenciaCarga.ValueDisplayFormat = '%5.2f';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Text = 'Factor de Potencia';
            app.FactorPotencia = uieditfield(app.Panel, 'numeric');
            app.FactorPotencia.ValueChangedFcn = createCallbackFcn(app, @FactorPotenciaValueChanged);
            app.FactorPotencia.Limits = [0 1];
            app.FactorPotencia.ValueDisplayFormat = '%1.2f';
            app.Label8 = uilabel(app.Panel);
            app.Label8.Text = 'Temp. de operación [°C]';
            app.TempOp = uieditfield(app.Panel, 'numeric');
            app.TempOp.ValueChangedFcn = createCallbackFcn(app, @TempOpValueChanged);
            app.TempOp.Limits = [0 150];
            app.TempOp.ValueDisplayFormat = '%1.2f';
            app.DistribucionPanel = uipanel(app.DatosEntrada);
            app.DistribucionPanel.Title = 'Distribución';
            app.DistribucionPanel.Units = 'pixels';
            app.Label2 = uilabel(app.DistribucionPanel);
            app.Label2.Text = 'Distribución';
            app.Distribucion = uidropdown(app.DistribucionPanel);
            app.Distribucion.Items = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distribucion.ValueChangedFcn = createCallbackFcn(app, @DistribucionValueChanged, true);
            app.Distribucion.Value = 'Cuadrada';
            app.Label9 = uilabel(app.DistribucionPanel);
            app.Label9.Text = 'Distancia [m]';
            app.Distancia = uieditfield(app.DistribucionPanel, 'numeric');
            app.Distancia.ValueChangedFcn = createCallbackFcn(app, @DistanciaValueChanged);
            app.Distancia.Limits = [0 50];
            app.Distancia.ValueDisplayFormat = '%1.2f';
            app.RLC = uipanel(app.CalculoParametros);
            app.RLC.Title = 'Parámetros R, L y C';
            app.RLC.Units = 'pixels';
            app.LabelTextArea = uilabel(app.RLC);
            app.LabelTextArea.Text = 'Resistencia [Ohms]';
            app.Res = uitextarea(app.RLC);
            app.Res.Editable = 'off';
            app.Label4 = uilabel(app.RLC);
            app.Label4.Text = 'Inductancia [H]';
            app.Ind = uitextarea(app.RLC);
            app.Ind.Editable = 'off';
            app.Label5 = uilabel(app.RLC);
            app.Label5.Text = 'Capacitancia [F]';
            app.Cap = uitextarea(app.RLC);
            app.Cap.Editable = 'off';
            app.BotonCalcRLC = uibutton(app.RLC, 'push');
            app.BotonCalcRLC.ButtonPushedFcn = createCallbackFcn(app, @BotonCalcRLCButtonPushed);
            app.BotonCalcRLC.Icon = 'LT.jpg';
            app.BotonCalcRLC.Text = 'Calcular';
            app.TablaComparativa = uitab(app.TabGroup);
            app.TablaComparativa.Units = 'pixels';
            app.TablaComparativa.Title = 'Tabla Comparativa';
            app.CuadroComparativo = uipanel(app.TablaComparativa);
            app.CuadroComparativo.Title = 'Cuadro comparativo';
            app.CuadroComparativo.Units = 'pixels';
            app.LabelTextArea2 = uilabel(app.CuadroComparativo);
            app.LabelTextArea2.Text = 'Línea';
            app.Info = uipanel(app.CuadroComparativo);
            app.Info.Units = 'pixels';
            app.Label10 = uilabel(app.Info);
            app.Label10.Text = 'V Trans [V]';
            app.MagVTrans1 = uitextarea(app.Info);
            app.Label12 = uilabel(app.Info);
            app.Label12.Text = 'I Trans [A]';
            app.MagITrans1 = uitextarea(app.Info);
            app.Label13 = uilabel(app.Info);
            app.Label13.Text = 'Fase I Trans';
            app.FasITrans1 = uitextarea(app.Info);
            app.Label14 = uilabel(app.Info);
            app.Label14.Text = 'V Línea [V]';
            app.MagVLinea1 = uitextarea(app.Info);
            app.Label11 = uilabel(app.Info);
            app.Label11.Text = 'Fase V Trans';
            app.FasVTrans1 = uitextarea(app.Info);
            app.Label15 = uilabel(app.Info);
            app.Label15.Text = 'Fase V Línea';
            app.FasVLinea1 = uitextarea(app.Info);
            app.Label16 = uilabel(app.Info);
            app.Label16.Text = '%Reg';
            app.Reg1 = uitextarea(app.Info);
            app.MagVTrans2 = uitextarea(app.Info);
            app.FasVTrans2 = uitextarea(app.Info);
            app.MagITrans2 = uitextarea(app.Info);
            app.FasITrans2 = uitextarea(app.Info);
            app.MagVLinea2 = uitextarea(app.Info);
            app.FasVLinea2 = uitextarea(app.Info);
            app.Reg2 = uitextarea(app.Info);
            app.MagVTrans3 = uitextarea(app.Info);
            app.FasVTrans3 = uitextarea(app.Info);
            app.MagITrans3 = uitextarea(app.Info);
            app.FasITrans3 = uitextarea(app.Info);
            app.MagVLinea3 = uitextarea(app.Info);
            app.FasVLinea3 = uitextarea(app.Info);
            app.Reg3 = uitextarea(app.Info);
            app.L_corta = uitextarea(app.CuadroComparativo);
            app.L_corta.Value = {'Línea Corta'};
            app.L_media = uitextarea(app.CuadroComparativo);
            app.L_media.Value = {'Línea Media'};
            app.L_larga = uitextarea(app.CuadroComparativo);
            app.L_larga.Value = {'Línea Larga'};
            app.Label17 = uilabel(app.TablaComparativa);
            app.Label17.Text = 'Comparación: Cálculo de línea corta, larga y media.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calc_LT()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end